classdef MyProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ActionButtonGroup     matlab.ui.container.ButtonGroup
        ChooseActionButton    matlab.ui.control.RadioButton
        DeNoisingStd1Button   matlab.ui.control.RadioButton
        DeNoisingStd2Button   matlab.ui.control.RadioButton
        DeNoisingStd5Button   matlab.ui.control.RadioButton
        DeNoisingStd10Button  matlab.ui.control.RadioButton
        DeNoisingStd15Button  matlab.ui.control.RadioButton
        DeNoisingStd20Button  matlab.ui.control.RadioButton
        DeNoisingStd25Button  matlab.ui.control.RadioButton
        SuperResolution510x510Scale2Button  matlab.ui.control.RadioButton
        SuperResolution510x510Scale3Button  matlab.ui.control.RadioButton
        DeNoisingAndSuperResolution510x510Scale2Std5Button  matlab.ui.control.RadioButton
        DeNoisingAndSuperResolution510x510Scale2Std10Button  matlab.ui.control.RadioButton
        InputImage            matlab.ui.control.Image
        OutputImage           matlab.ui.control.Image
        InputImageLabel       matlab.ui.control.Label
        OutputImageLabel      matlab.ui.control.Label
        BrowseImageButton     matlab.ui.control.Button
        Message               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        netDeNoisingStd1 = importKerasNetwork("netDeNoisingStd1.h5"); 
        netDeNoisingStd2 = importKerasNetwork("netDeNoisingStd2.h5");
        netDeNoisingStd5 = importKerasNetwork("netDeNoisingStd5.h5");
        netDeNoisingStd10 = importKerasNetwork("netDeNoisingStd10.h5");
        netDeNoisingStd15 = importKerasNetwork("netDeNoisingStd15.h5");
        netDeNoisingStd20 = importKerasNetwork("netDeNoisingStd20.h5");
        netDeNoisingStd25 = importKerasNetwork("netDeNoisingStd25.h5");
        netSuperResolution510x510Scale2 = importKerasNetwork("netSuperResolution510x510Scale2.h5");
        netSuperResolution510x510Scale3 = importKerasNetwork("netSuperResolution510x510Scale3.h5");
        netDeNoisingAndSuperResolution510x510Scale2Std5 = importKerasNetwork("netDeNoisingAndSuperResolution510x510Scale2Std5.h5");
        netDeNoisingAndSuperResolution510x510Scale2Std10 = importKerasNetwork("netDeNoisingAndSuperResolution510x510Scale2Std10.h5");
        normalizedStd1 = load("normalizedStd1.mat");
        normalizedStd2 = load("normalizedStd2.mat");
        normalizedStd5 = load("normalizedStd5.mat");
        normalizedStd10 = load("normalizedStd10.mat");
        normalizedStd15 = load("normalizedStd15.mat");
        normalizedStd20 = load("normalizedStd20.mat");
        normalizedStd25 = load("normalizedStd25.mat");
        SLT = load("SLT_P.mat");
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
             % select image to work on
            [InputImageName, path] = uigetfile({'*.*'}, 'Select file');
            [path,InputImageName] = convertCharsToStrings(path,InputImageName);
            InputImagePath = path + InputImageName;
            figure(app.UIFigure); % maximaize GUI window
            app.OutputImage.ImageSource = "";
            app.Message.Value = "";
            drawnow;          
            app.ChooseActionButton.Value = true; % defining default button select                 
            if (num2str(InputImagePath) == "0") % if image not selected
                uiwait(msgbox({'Image not selected!'},'ERROR','error'));
                figure(app.UIFigure); % maximaize GUI window
            else % image selected
                tempImage = imread(InputImagePath);
                if (size(tempImage,3) ~= 3)
                    tempImage = cat(3,tempImage,tempImage,tempImage);                    
                end
                app.InputImage.ImageSource = tempImage;
            end
        end

        % Selection changed function: ActionButtonGroup
        function ActionButtonGroupSelectionChanged(app, event)
            app.OutputImage.ImageSource = "";
            drawnow;
            selectedButton = app.ActionButtonGroup.SelectedObject;
            if (selectedButton ~= app.ChooseActionButton && class(app.InputImage.ImageSource) ~= "uint8") % if image not selected
                uiwait(msgbox({'Image not selected!'},'ERROR','error'));
                app.Message.Value = "";
                drawnow;
                figure(app.UIFigure); % maximaize GUI window 
            else
                app.Message.Value = "Wait...";
                drawnow;
                if (selectedButton == app.DeNoisingStd1Button || selectedButton == app.DeNoisingStd2Button ||...
                       selectedButton == app.DeNoisingStd5Button || selectedButton == app.DeNoisingStd10Button  ||...
                       selectedButton == app.DeNoisingStd15Button || selectedButton == app.DeNoisingStd20Button  ||...
                       selectedButton == app.DeNoisingStd25Button)
                    Image = app.InputImage.ImageSource; %image from gui is always RGB
                    Image = rgb2gray(Image);
                    %Image = imcrop(Image,[0,0,510,510]);
                    app.InputImage.ImageSource = cat(3,Image,Image,Image);
                    switch selectedButton
                        case app.DeNoisingStd1Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd1,...
                                app.normalizedStd1.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                        case app.DeNoisingStd2Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd2,...
                                app.normalizedStd2.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                        case app.DeNoisingStd5Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd5,...
                                app.normalizedStd5.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                        case app.DeNoisingStd10Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd10,...
                                app.normalizedStd10.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                        case app.DeNoisingStd15Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd15,...
                                app.normalizedStd15.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                        case app.DeNoisingStd20Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd20,...
                                app.normalizedStd20.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                        case app.DeNoisingStd25Button
                            ImageRepairByNet = DeNoisingOneImageFunction(Image,app.netDeNoisingStd25,...
                                app.normalizedStd25.normalized,app.SLT.H,app.SLT.H_trans,app.SLT.K,app.SLT.N);
                    end
                    app.OutputImage.ImageSource = cat(3,ImageRepairByNet,ImageRepairByNet,ImageRepairByNet);   
                elseif (selectedButton == app.SuperResolution510x510Scale2Button ||...
                        selectedButton == app.DeNoisingAndSuperResolution510x510Scale2Std5Button ||...
                        selectedButton == app.DeNoisingAndSuperResolution510x510Scale2Std10Button)
                    Image = app.InputImage.ImageSource; %image from gui is always RGB 
                    Image = imcrop(Image,[0,0,255,255]);
                    sizeImage = size(Image);
                    if (sizeImage(1)<255 || sizeImage(2)<255)
                        uiwait(msgbox({'The image should be at least in size 255X255'},'ERROR','error'));
                        figure(app.UIFigure); % maximaize GUI window
                    else
                        app.InputImage.ImageSource = Image;
                        switch selectedButton
                            case app.SuperResolution510x510Scale2Button
                                ImageRepairByNet = SuperResolutionOneImageFunction(Image,app.netSuperResolution510x510Scale2,2);
                            case app.DeNoisingAndSuperResolution510x510Scale2Std5Button
                                ImageRepairByNet = SuperResolutionOneImageFunction(Image,app.netDeNoisingAndSuperResolution510x510Scale2Std5,2);
                            case app.DeNoisingAndSuperResolution510x510Scale2Std10Button
                                ImageRepairByNet = SuperResolutionOneImageFunction(Image,app.netDeNoisingAndSuperResolution510x510Scale2Std10,2);
                        end
                        app.OutputImage.ImageSource = ImageRepairByNet;
                    end   
                elseif (selectedButton == app.SuperResolution510x510Scale3Button)
                    Image = app.InputImage.ImageSource; %image from gui is always RGB                                       
                    Image = imcrop(Image,[0,0,170,170]);
                    sizeImage = size(Image);
                    if (sizeImage(1)<170 || sizeImage(2)<170)
                        uiwait(msgbox({'The image should be at least in size 170X170'},'ERROR','error'));
                        figure(app.UIFigure); % maximaize GUI window
                    else
                        app.InputImage.ImageSource = Image;
                        ImageRepairByNet = SuperResolutionOneImageFunction(Image,app.netSuperResolution510x510Scale3,3);
                        app.OutputImage.ImageSource = ImageRepairByNet;
                    end 
                end
                app.Message.Value = "Done!";
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ActionButtonGroup = uibuttongroup(app.UIFigure);
            app.ActionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ActionButtonGroupSelectionChanged, true);
            app.ActionButtonGroup.Title = 'Action';
            app.ChooseActionButton = uiradiobutton(app.ActionButtonGroup);
            app.ChooseActionButton.Text = 'Choose Action';
            app.ChooseActionButton.Value = true;
            app.DeNoisingStd1Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd1Button.Text = 'DeNoising Std1';
            app.DeNoisingStd2Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd2Button.Text = 'DeNoising Std2';
            app.DeNoisingStd5Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd5Button.Text = 'DeNoising Std5';
            app.DeNoisingStd10Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd10Button.Text = 'DeNoising Std10';
            app.DeNoisingStd15Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd15Button.Text = 'DeNoising Std15';
            app.DeNoisingStd20Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd20Button.Text = 'DeNoising Std20';
            app.DeNoisingStd25Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingStd25Button.Text = 'DeNoising Std25';
            app.SuperResolution510x510Scale2Button = uiradiobutton(app.ActionButtonGroup);
            app.SuperResolution510x510Scale2Button.Text = 'SuperResolution 510x510 Scale2';
            app.SuperResolution510x510Scale3Button = uiradiobutton(app.ActionButtonGroup);
            app.SuperResolution510x510Scale3Button.Text = 'SuperResolution 510x510 Scale3 ';
            app.DeNoisingAndSuperResolution510x510Scale2Std5Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingAndSuperResolution510x510Scale2Std5Button.Text = 'DeNoisingAndSuperResolution 510x510 Scale2 Std5';
            app.DeNoisingAndSuperResolution510x510Scale2Std10Button = uiradiobutton(app.ActionButtonGroup);
            app.DeNoisingAndSuperResolution510x510Scale2Std10Button.Text = 'DeNoisingAndSuperResolution 510x510 Scale2 Std10';
            app.InputImage = uiimage(app.UIFigure);
            app.OutputImage = uiimage(app.UIFigure);
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Text = 'Output Image';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.Message = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end